classdef NLAQualityControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        HeadMotion           matlab.ui.control.Button
        MotionFieldLabel     matlab.ui.control.Label
        MotionFieldDropDown  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        test_pool
        input_struct
        net_input_struct
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, test_pool, input_struct, net_input_struct)
            import nla.* % required due to matlab package system quirks
            
            app.UIFigure.Name = 'NLA Quality Control';
            app.UIFigure.Icon = [findRootPath() 'thumb.png'];
            
            app.test_pool = test_pool;
            app.input_struct = input_struct;
            app.net_input_struct = net_input_struct;
            
            app.MotionFieldDropDown.Items = app.input_struct.behavior_full.Properties.VariableNames;
        end

        % Button pushed function: HeadMotion
        function HeadMotionButtonPushed(app, event)
            import nla.* % required due to matlab package system quirks
            motion = app.input_struct.behavior_full.(app.MotionFieldDropDown.Value);
            qualityControl.checkHeadMotion(app.UIFigure, app.input_struct, motion);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HeadMotion = uibutton(app.UIFigure, 'push');
            app.HeadMotion.ButtonPushedFcn = createCallbackFcn(app, @HeadMotionButtonPushed, true);
            app.HeadMotion.Text = 'Check head motion';
            app.MotionFieldLabel = uilabel(app.UIFigure);
            app.MotionFieldLabel.Text = 'Motion Field:';
            app.MotionFieldDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLAQualityControl(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end